classdef POTD_selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WhichotherplansprovideadequatecoverageifanyEditField  matlab.ui.control.EditField
        WhichotherplansprovideadequatecoverageifanyEditFieldLabel  matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        Image2_2                        matlab.ui.control.Image
        commandiLabel_6                 matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        commandiLabel_5                 matlab.ui.control.Label
        commandiLabel_4                 matlab.ui.control.Label
        iLabel                          matlab.ui.control.Label
        commandiLabel_3                 matlab.ui.control.Label
        commandiLabel_2                 matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        commandiLabel                   matlab.ui.control.Label
        ImageviewershortcutsandrecommendeddefaultsLabel  matlab.ui.control.Label
        TimerstoppedLabel               matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        HowconfidentareyouthatyourselectedplanisthemostappropriateLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        visibilityButtonGroup           matlab.ui.container.ButtonGroup
        NoresponseButton_3              matlab.ui.control.RadioButton
        GoodvisibilityMostorallboundariesclearlyvisualizedLabel  matlab.ui.control.Label
        Button_4                        matlab.ui.control.RadioButton
        ModeratevisibilityCombinationofclearandunclearboundariesLabel  matlab.ui.control.Label
        Button_3                        matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        PoorvisibilityMostboundariesdifficulttodiscernLabel  matlab.ui.control.Label
        NotvisibleorbarelyvisibleLabel  matlab.ui.control.Label
        Button                          matlab.ui.control.RadioButton
        SaveandcontinueButton           matlab.ui.control.Button
        SaveandexitButton               matlab.ui.control.Button
        confidenceButtonGroup           matlab.ui.container.ButtonGroup
        NoresponseButton_2              matlab.ui.control.RadioButton
        ExtremelyconfidentLabel         matlab.ui.control.Label
        ModeratelyconfidentLabel        matlab.ui.control.Label
        AlittleconfidentLabel           matlab.ui.control.Label
        NotconfidentatallLabel          matlab.ui.control.Label
        Button_8                        matlab.ui.control.RadioButton
        Button_7                        matlab.ui.control.RadioButton
        Button_6                        matlab.ui.control.RadioButton
        Button_5                        matlab.ui.control.RadioButton
        ButtonGroup                     matlab.ui.container.ButtonGroup
        NoresponseButton                matlab.ui.control.RadioButton
        FinalizeSelectionButton         matlab.ui.control.Button
        NonewouldgetpatientoffcouchButton  matlab.ui.control.RadioButton
        PTV45_RobustButton              matlab.ui.control.RadioButton
        PTV45_3Button                   matlab.ui.control.RadioButton
        PTV45_2Button                   matlab.ui.control.RadioButton
        PTV45_1Button                   matlab.ui.control.RadioButton
        Loadimage1of24Button            matlab.ui.control.Button
        ResumeanalysisButton            matlab.ui.control.Button
        StartnewanalysisButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        Excel_template
        USB_filepath
        Counter
        response_filepath
        workspace_names
        workspace_dir
        file_number
        selectedPTV
        timerVal
        confidence_rating
        visibility_rating
        selected_module
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartnewanalysisButton
        function StartnewanalysisButtonPushed(app, event)
            %Generate dialogue box so user can enter initials for saving
            %output excel file
            prompt = "Enter your initials";
            initials = inputdlg(prompt);

            %Extract file number for display on 'load image' button
            C = readcell(app.Excel_template);
            app.workspace_names = C(2:end,4);
            app.Counter = 1;
            app.file_number = size(C,1) - 1;
            formatSpec = 'Load image %d of %d';
            str = sprintf(formatSpec, app.Counter, app.file_number);
            app.Loadimage1of24Button.Text = str;
            
             %Copy original file to USB stick and rename to
            %'initials_responses.xlsx'
            if contains(app.selected_module, 'practice')
                new_name = strcat(initials,  '_practice_responses.xlsx');
            elseif contains(app.selected_module, 'full')
                new_name = strcat(initials, '_fullAnalysis_responses.xlsx');
            end

            new_file = fullfile(app.USB_filepath, new_name);

            if isfile(new_file)
                fig = uifigure;
                msg = 'A file with this name already exists. Would you like to overwrite?';
                title = 'Confirm Save';
                selection = uiconfirm(fig, msg, title, 'Options', {'Overwrite', 'Save as new', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption',3);
                selection = convertCharsToStrings(selection);
                 if selection == "Overwrite"
                     copyfile(app.Excel_template, new_file{1})
                     app.Loadimage1of24Button.Enable = 'on';
                 elseif selection == "Save as new"
                     prompt = 'Enter unique name';
                     initials = inputdlg(prompt);
                     new_name = strcat(initials,  '_responses.xlsx');
                     new_file = fullfile(app.USB_filepath, new_name);
                     copyfile(app.Excel_template, new_file{1})
                     app.Loadimage1of24Button.Enable = 'on';
                 end
            else
                copyfile(app.Excel_template, new_file{1});
                app.Loadimage1of24Button.Enable = 'on';
            end

            app.response_filepath = new_file;

            %Disable Start and Resume analysis buttons
            app.StartnewanalysisButton.Enable = 'off';
            app.ResumeanalysisButton.Enable = 'off';
            
        end

        % Button pushed function: Loadimage1of24Button
        function Loadimage1of24ButtonPushed(app, event)
            ind = app.Counter;

            %Update counter and disable'Load next image' button text
            app.Counter = app.Counter + 1;
            app.Loadimage1of24Button.Enable = 'off';

            %Ensure 'No Response' radio button is selected
            app.NoresponseButton.Value = true;
           

            %Check if running on Windows
            TF = ispc;

            %Check that ind has not exceeded number of available workspaces
            l = length(app.workspace_names);
            if ind <= l
                workspace_name = app.workspace_names{ind};
                workspace_path = fullfile(app.workspace_dir, workspace_name);

                %Read workspace file and verify that SaveLocation entry
                %points to 'worskpaces' directory (where .itsknap files are
                %saved)
                fid = fopen(workspace_path);
                txt = textscan(fid, '%s', 'delimiter', '\n');
                test = txt{1,1};
                row_index = find(contains(test, 'entry key="SaveLocation"'));
                row = test{row_index};
                TFw = contains(row, "workspaces");
                fclose(fid);

                if ~TFw
                    %Replace text after 'nifti_dump' to correct
                    %"workspaces" subdirectory
                    root_dir = extractBefore(row, "nifti_dump");
                    lastbit = [' ' filesep '>'];
                    character_str = '"' + convertCharsToStrings(lastbit);
                    fname = "workspaces" + character_str;
                    newStr = fullfile(root_dir, "nifti_dump", fname);
                    newChar = convertStringsToChars(newStr);
                    txt{1,1}{row_index} = newChar;

                    %Overwrite existing file
                    fid = fopen(workspace_path, 'w');
                    fprintf(fid, '%s\n', txt{1,1}{:});
                    fclose(fid);
                end


                if TF
                    command_cell = strcat('itk-snap -w', {' '}, workspace_path, {' '}, '-z', {' '}, '1.2', {' '}, '&');
                else

                    command_cell = strcat('itksnap -w', {' '}, workspace_path, {' '}, '-z', {' '}, '1.2', {' '}, '&');
                end


                command = command_cell{1};
                system(command);
                
                %Start timer
                app.timerVal = tic;
                app.TimerstoppedLabel.Text = 'Timer started';

         
            else
                f = msgbox('Analysis Completed');

            end


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.selectedPTV = selectedButton.Text;

        end

        % Button pushed function: FinalizeSelectionButton
        function FinalizeSelectionButtonPushed(app, event)

            %Check to see whether plan selection has been made
            test = app.ButtonGroup.SelectedObject.Text
            noresponse_txt = app.NoresponseButton.Text
            tf = strcmp(test, noresponse_txt)
            

            if ~tf

                %Stop timer
                tEnd = toc(app.timerVal);
                app.TimerstoppedLabel.Text = 'Timer stopped';

                %Populate excel file with selected PTV
                c = {app.selectedPTV};
                formatSpec = 'E%d:E%d';
                str = sprintf(formatSpec, app.Counter, app.Counter);
                writecell(c, app.response_filepath{1}, 'Sheet', 1, 'Range', str)

                %Populate excel file with elapsed time
                c2 = {tEnd};
                formatSpec2 = 'F%d:F%d';
                str2 = sprintf(formatSpec2, app.Counter, app.Counter);
                writecell(c2, app.response_filepath{1}, 'Sheet', 1, 'Range', str2)

                %Disable button
                app.FinalizeSelectionButton.Enable = 'off';

                %Enable Confidence and Visibility button groups
                app.confidenceButtonGroup.Enable = 'on';
                app.visibilityButtonGroup.Enable = 'on';

            else
                f = msgbox('Please select plan before proceeding');
            end


        end

        % Button pushed function: ResumeanalysisButton
        function ResumeanalysisButtonPushed(app, event)
            %Allow user to select previously saved file
            [file,path] = uigetfile('*.xlsx',...
               'Select previously saved file', app.USB_filepath);
            new_file = fullfile(path, file);
            app.response_filepath = {new_file};

            %Read user's excel file and extract relevant properties
            C = readcell(app.response_filepath{1});
            app.workspace_names = C(2:end,4);
            app.file_number = size(C,1) - 1;

            %Determine where user left off
            previous_responses = C(2:end,5);
            tf = zeros(1, length(previous_responses));
            for i = 1:length(previous_responses)
                tf(:,i) = ischar(previous_responses{i});
            end

            %Update Counter
            app.Counter = sum(tf) + 1;

            %Update Load image button text and enable
            formatSpec = 'Load image %d of %d';
            str = sprintf(formatSpec, app.Counter, app.file_number);
            app.Loadimage1of24Button.Text = str;
            app.Loadimage1of24Button.Enable = 'on';

            %Disable Start and Resume analysis buttons
            app.StartnewanalysisButton.Enable = 'off';
            app.ResumeanalysisButton.Enable = 'off';


        end

        % Selection changed function: confidenceButtonGroup
        function confidenceButtonGroupSelectionChanged(app, event)
            selectedButton = app.confidenceButtonGroup.SelectedObject;
            app.confidence_rating = {str2double(selectedButton.Text)};
            
        end

        % Selection changed function: visibilityButtonGroup
        function visibilityButtonGroupSelectionChanged(app, event)
            selectedButton = app.visibilityButtonGroup.SelectedObject;
            app.visibility_rating = {str2double(selectedButton.Text)};
            
        end

        % Button pushed function: SaveandcontinueButton
        function SaveandcontinueButtonPushed(app, event)
            % Play audio
            fname = 'mixkit-bike-magical-bell-591.wav';
            [y, Fs] = audioread(fname);
            sound(y, Fs)

            %Extract any comments (if available)and write to excel sheet
            observer_comments = {app.WhichotherplansprovideadequatecoverageifanyEditField.Value};
            formatSpec2 = 'I%d:I%d';
            str2 = sprintf(formatSpec2, app.Counter, app.Counter);
            writecell(observer_comments, app.response_filepath{1}, 'Sheet', 1, 'Range', str2)
            
            %Write confidence rating to excel sheet
            formatSpec2 = 'G%d:G%d';
            str2 = sprintf(formatSpec2, app.Counter, app.Counter);
            writecell(app.confidence_rating, app.response_filepath{1}, 'Sheet', 1, 'Range', str2)

            %Write visibility rating to excel sheet
            formatSpec2 = 'H%d:H%d';
            str2 = sprintf(formatSpec2, app.Counter, app.Counter);
            writecell(app.visibility_rating, app.response_filepath{1}, 'Sheet', 1, 'Range', str2)
            

            %Update Load image button text and enable
            formatSpec = 'Load image %d of %d';
            str = sprintf(formatSpec, app.Counter, app.file_number);
            app.Loadimage1of24Button.Text = str;
            app.Loadimage1of24Button.Enable = 'on';

            %Re-enable 'Finalize Selection' button
            app.FinalizeSelectionButton.Enable = 'on';

            %Reset plan, confidence, and visibility buttons to default
            app.NoresponseButton.Value = 1;
            app.NoresponseButton_2.Value = 1;
            app.NoresponseButton_3.Value = 1;

            %Disable Confidence and Visibility button groups
            app.confidenceButtonGroup.Enable = 'off';
            app.visibilityButtonGroup.Enable = 'off';

            %Clear textbox
            app.WhichotherplansprovideadequatecoverageifanyEditField.Value = '';

        end

        % Button pushed function: SaveandexitButton
        function SaveandexitButtonPushed(app, event)
            % Play audio
            fname = 'mixkit-arcade-score-interface-217.wav';
            [y, Fs] = audioread(fname);
            sound(y, Fs)

            %Extract any comments (if available)and write to excel sheet
            observer_comments = {app.WhichotherplansprovideadequatecoverageifanyEditField.Value};
            formatSpec2 = 'I%d:I%d';
            str2 = sprintf(formatSpec2, app.Counter, app.Counter);
            writecell(observer_comments, app.response_filepath{1}, 'Sheet', 1, 'Range', str2)
            
            
            %Write confidence rating to excel sheet
            formatSpec2 = 'G%d:G%d';
            str2 = sprintf(formatSpec2, app.Counter, app.Counter);
            writecell(app.confidence_rating, app.response_filepath{1}, 'Sheet', 1, 'Range', str2)

            %Write visibility rating to excel sheet
            formatSpec2 = 'H%d:H%d';
            str2 = sprintf(formatSpec2, app.Counter, app.Counter);
            writecell(app.visibility_rating, app.response_filepath{1}, 'Sheet', 1, 'Range', str2)

            %Clear textbox
            app.WhichotherplansprovideadequatecoverageifanyEditField.Value = '';
            
            %Close app
            app.delete
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)

            %Read init file to determine name of drive where USB is mounted
            TF = ispc;
            if TF %for PC
                C = readcell('init_pc.xlsx');
                app.USB_filepath = [C{1}];
                app.workspace_dir = [C{1} filesep 'nifti_dump' filesep 'workspaces'];
            else %For mac
                C = readcell('init_mac.xlsx');
                app.USB_filepath = [filesep C{1} filesep 'SarahB_USB'];
                app.workspace_dir = [filesep C{1} filesep 'SarahB_USB' filesep 'nifti_dump' filesep 'workspaces'];
            end


            selectedModule = app.ListBox.Value;
            TF_p = contains(selectedModule, 'Practice');
            TF_f = contains(selectedModule, 'Full analysis');
%             %Direct program to corresponding excel file
             if TF_p
                app.Excel_template = fullfile(app.USB_filepath, 'practice_workspace_list_template.xlsx');
                app.selected_module = 'practice';
             elseif TF_f
                app.Excel_template = fullfile(app.USB_filepath, 'workspace_list_template.xlsx'); % fpath to template excel file with all available workspaces'
                app.selected_module = 'full';
            end

            %Enable start/resume analysis buttons
            app.StartnewanalysisButton.Enable = 'on';
            app.ResumeanalysisButton.Enable = 'on';

            %Disable module select button group
            app.ListBox.Enable = 'off';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartnewanalysisButton = uibutton(app.UIFigure, 'push');
            app.StartnewanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartnewanalysisButtonPushed, true);
            app.StartnewanalysisButton.Enable = 'off';
            app.StartnewanalysisButton.Text = 'Start new analysis';
            app.ResumeanalysisButton = uibutton(app.UIFigure, 'push');
            app.ResumeanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeanalysisButtonPushed, true);
            app.ResumeanalysisButton.Enable = 'off';
            app.ResumeanalysisButton.Text = 'Resume analysis';
            app.Loadimage1of24Button = uibutton(app.UIFigure, 'push');
            app.Loadimage1of24Button.ButtonPushedFcn = createCallbackFcn(app, @Loadimage1of24ButtonPushed, true);
            app.Loadimage1of24Button.Enable = 'off';
            app.Loadimage1of24Button.Text = 'Load image 1 of 24';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PTV45_1Button = uiradiobutton(app.ButtonGroup);
            app.PTV45_1Button.Text = 'PTV45_1';
            app.PTV45_2Button = uiradiobutton(app.ButtonGroup);
            app.PTV45_2Button.Text = 'PTV45_2';
            app.PTV45_3Button = uiradiobutton(app.ButtonGroup);
            app.PTV45_3Button.Text = 'PTV45_3';
            app.PTV45_RobustButton = uiradiobutton(app.ButtonGroup);
            app.PTV45_RobustButton.Text = 'PTV45_Robust';
            app.NonewouldgetpatientoffcouchButton = uiradiobutton(app.ButtonGroup);
            app.NonewouldgetpatientoffcouchButton.Text = 'None (would get patient off couch)';
            app.FinalizeSelectionButton = uibutton(app.ButtonGroup, 'push');
            app.FinalizeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizeSelectionButtonPushed, true);
            app.FinalizeSelectionButton.Text = 'Finalize Selection';
            app.NoresponseButton = uiradiobutton(app.ButtonGroup);
            app.NoresponseButton.Visible = 'off';
            app.NoresponseButton.Text = 'No response';
            app.NoresponseButton.Value = true;
            app.confidenceButtonGroup = uibuttongroup(app.UIFigure);
            app.confidenceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @confidenceButtonGroupSelectionChanged, true);
            app.confidenceButtonGroup.Enable = 'off';
            app.Button_5 = uiradiobutton(app.confidenceButtonGroup);
            app.Button_5.Text = ' 0';
            app.Button_6 = uiradiobutton(app.confidenceButtonGroup);
            app.Button_6.Text = ' 1';
            app.Button_7 = uiradiobutton(app.confidenceButtonGroup);
            app.Button_7.Text = ' 2';
            app.Button_8 = uiradiobutton(app.confidenceButtonGroup);
            app.Button_8.Text = ' 3';
            app.NotconfidentatallLabel = uilabel(app.confidenceButtonGroup);
            app.NotconfidentatallLabel.Text = 'Not confident at all';
            app.AlittleconfidentLabel = uilabel(app.confidenceButtonGroup);
            app.AlittleconfidentLabel.Text = 'A little confident';
            app.ModeratelyconfidentLabel = uilabel(app.confidenceButtonGroup);
            app.ModeratelyconfidentLabel.Text = 'Moderately confident';
            app.ExtremelyconfidentLabel = uilabel(app.confidenceButtonGroup);
            app.ExtremelyconfidentLabel.Text = 'Extremely confident';
            app.NoresponseButton_2 = uiradiobutton(app.confidenceButtonGroup);
            app.NoresponseButton_2.Enable = 'off';
            app.NoresponseButton_2.Visible = 'off';
            app.NoresponseButton_2.Text = 'No response';
            app.NoresponseButton_2.Value = true;
            app.SaveandexitButton = uibutton(app.UIFigure, 'push');
            app.SaveandexitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandexitButtonPushed, true);
            app.SaveandexitButton.Text = 'Save and exit';
            app.SaveandcontinueButton = uibutton(app.UIFigure, 'push');
            app.SaveandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandcontinueButtonPushed, true);
            app.SaveandcontinueButton.Text = 'Save and continue';
            app.visibilityButtonGroup = uibuttongroup(app.UIFigure);
            app.visibilityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @visibilityButtonGroupSelectionChanged, true);
            app.visibilityButtonGroup.Enable = 'off';
            app.Button = uiradiobutton(app.visibilityButtonGroup);
            app.Button.Text = ' 0';
            app.NotvisibleorbarelyvisibleLabel = uilabel(app.visibilityButtonGroup);
            app.NotvisibleorbarelyvisibleLabel.Text = 'Not visible or barely visible';
            app.PoorvisibilityMostboundariesdifficulttodiscernLabel = uilabel(app.visibilityButtonGroup);
            app.PoorvisibilityMostboundariesdifficulttodiscernLabel.Text = 'Poor visibility. Most boundaries difficult to discern';
            app.Button_2 = uiradiobutton(app.visibilityButtonGroup);
            app.Button_2.Text = ' 1';
            app.Button_3 = uiradiobutton(app.visibilityButtonGroup);
            app.Button_3.Text = ' 2';
            app.ModeratevisibilityCombinationofclearandunclearboundariesLabel = uilabel(app.visibilityButtonGroup);
            app.ModeratevisibilityCombinationofclearandunclearboundariesLabel.Text = 'Moderate visibility. Combination of clear and unclear boundaries.';
            app.Button_4 = uiradiobutton(app.visibilityButtonGroup);
            app.Button_4.Text = ' 3';
            app.GoodvisibilityMostorallboundariesclearlyvisualizedLabel = uilabel(app.visibilityButtonGroup);
            app.GoodvisibilityMostorallboundariesclearlyvisualizedLabel.Text = 'Good visibility. Most or all boundaries clearly visualized';
            app.NoresponseButton_3 = uiradiobutton(app.visibilityButtonGroup);
            app.NoresponseButton_3.Enable = 'off';
            app.NoresponseButton_3.Visible = 'off';
            app.NoresponseButton_3.Text = 'No response';
            app.NoresponseButton_3.Value = true;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {' 3. Rate the visibility of the uterocervix. If both US and CBCT are available, provide an overall rating '; ' given the combined information obtained from both images.'};
            app.HowconfidentareyouthatyourselectedplanisthemostappropriateLabel = uilabel(app.UIFigure);
            app.HowconfidentareyouthatyourselectedplanisthemostappropriateLabel.Text = ' 2. How confident are you that your selected plan is the most appropriate?';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '1. POTD Selection';
            app.TimerstoppedLabel = uilabel(app.UIFigure);
            app.TimerstoppedLabel.Text = 'Timer stopped';
            app.ImageviewershortcutsandrecommendeddefaultsLabel = uilabel(app.UIFigure);
            app.ImageviewershortcutsandrecommendeddefaultsLabel.Text = 'Image viewer shortcuts and recommended defaults:';
            app.commandiLabel = uilabel(app.UIFigure);
            app.commandiLabel.Text = {'Zoom Inspector'; ''; ''};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'zoom_inspector.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'cursor_inspector.png';
            app.commandiLabel_2 = uilabel(app.UIFigure);
            app.commandiLabel_2.Text = {'Cursor Inspector. Updates orthogonal view slices when image clicked.'; ''};
            app.commandiLabel_3 = uilabel(app.UIFigure);
            app.commandiLabel_3.Text = {'Opens Image Layer Inspector:'; ''; ''};
            app.iLabel = uilabel(app.UIFigure);
            app.iLabel.Text = '⌘i';
            app.commandiLabel_4 = uilabel(app.UIFigure);
            app.commandiLabel_4.Text = 'Contrast tab: move yellow control points on histogram to adjust WWWL';
            app.commandiLabel_5 = uilabel(app.UIFigure);
            app.commandiLabel_5.Text = 'Eye: Click to toggle selected image/structure on or off';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'transparency_slider.png';
            app.commandiLabel_6 = uilabel(app.UIFigure);
            app.commandiLabel_6.Text = 'Transparency slider: slide to adjust opacity of selected layer';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'eye.png';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Please select module:', 'Practice', 'Full analysis'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Please select module:';
            app.WhichotherplansprovideadequatecoverageifanyEditFieldLabel = uilabel(app.UIFigure);
            app.WhichotherplansprovideadequatecoverageifanyEditFieldLabel.Text = 'Which other plans provide adequate coverage (if any)?';
            app.WhichotherplansprovideadequatecoverageifanyEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = POTD_selection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end